classdef EventPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectMeteorEventtoAnalyzeListBoxLabel  matlab.ui.control.Label
        SelectMeteorEventtoAnalyzeListBox  matlab.ui.control.ListBox
        OKButton            matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        SelectMeteorEventtoAnalyzeLabel  matlab.ui.control.Label
        StrewnLAB2023Label  matlab.ui.control.Label
        CheckBox_CONF       matlab.ui.control.CheckBox
        SelectMeteorEventtoAnalyzeListBoxLabel_2  matlab.ui.control.Label
        SelectMeteorEventtoAnalyzeListBoxLabel_3  matlab.ui.control.Label
        CheckBox_GE         matlab.ui.control.CheckBox
        SelectMeteorEventtoAnalyzeListBoxLabel_4  matlab.ui.control.Label
        CheckBox_WCT        matlab.ui.control.CheckBox
        CheckBox_VIDEO      matlab.ui.control.CheckBox
        SelectMeteorEventtoAnalyzeListBoxLabel_5  matlab.ui.control.Label
    end

    
    properties (Access = private)
        EventList % list of all events        
    end
    
    properties (Access = public)
        success
        OK_Pressed
        SelectedEvent % Selected event output
        CONFIDENTIAL % Description
        GE
        WCT
        VIDEO
    end
    
    methods (Access = public)

    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Database)
            app.SelectMeteorEventtoAnalyzeListBox.Items = strcat(Database.EventID, {' - '}, Database.Location, {' ('}, Database.AMS_event_id, {')'});
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.success = false;
        end

        % Value changed function: SelectMeteorEventtoAnalyzeListBox
        function SelectMeteorEventtoAnalyzeListBoxValueChanged(app, event)
            app.SelectedEvent = app.SelectMeteorEventtoAnalyzeListBox.Value;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if isempty(app.SelectedEvent)
                app.success = false;
            else
                app.success = true;
            end
            
            % assume not Confidential, unless checked
            if isempty(app.CONFIDENTIAL)
                app.CONFIDENTIAL = false;
            end
            
            % assume open WCT, unless unchecked
            if isempty(app.WCT)
                app.WCT = true;
            end
            
            % assume open WCT, unless unchecked
            if isempty(app.GE)
                app.GE = true;
            end
            
            % assume VIDEO, unless unchecked
            if isempty(app.VIDEO)
                app.VIDEO = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.success = false;
            % delete(app)  % not used, this picker should be called using the structure in strewnalyze to get outputs       
        end

        % Value changed function: CheckBox_CONF
        function CheckBox_CONFValueChanged(app, event)
            value = app.CheckBox_CONF.Value;
            app.CONFIDENTIAL = value;
            
        end

        % Value changed function: CheckBox_WCT
        function CheckBox_WCTValueChanged(app, event)
            value = app.CheckBox_WCT.Value;
            app.WCT = value;
            
        end

        % Value changed function: CheckBox_GE
        function CheckBox_GEValueChanged(app, event)
            value = app.CheckBox_GE.Value;
            app.GE = value;
        end

        % Value changed function: CheckBox_VIDEO
        function CheckBox_VIDEOValueChanged(app, event)
            value = app.CheckBox_VIDEO.Value;
            app.VIDEO = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectMeteorEventtoAnalyzeListBoxLabel = uilabel(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeListBoxLabel.Text = 'Select Meteor Event to Analyze:';
            app.SelectMeteorEventtoAnalyzeListBox = uilistbox(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectMeteorEventtoAnalyzeListBoxValueChanged, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectMeteorEventtoAnalyzeLabel = uilabel(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeLabel.Text = 'Select Meteor Event to Analyze';
            app.StrewnLAB2023Label = uilabel(app.UIFigure);
            app.StrewnLAB2023Label.Text = 'StrewnLAB © 2023';
            app.CheckBox_CONF = uicheckbox(app.UIFigure);
            app.CheckBox_CONF.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CONFValueChanged, true);
            app.SelectMeteorEventtoAnalyzeListBoxLabel_2 = uilabel(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeListBoxLabel_2.Text = 'CONFIDENTIAL';
            app.SelectMeteorEventtoAnalyzeListBoxLabel_3 = uilabel(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeListBoxLabel_3.Text = 'Open Google Earth';
            app.CheckBox_GE = uicheckbox(app.UIFigure);
            app.CheckBox_GE.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GEValueChanged, true);
            app.CheckBox_GE.Value = true;
            app.SelectMeteorEventtoAnalyzeListBoxLabel_4 = uilabel(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeListBoxLabel_4.Text = 'Open NOAA WCT';
            app.CheckBox_WCT = uicheckbox(app.UIFigure);
            app.CheckBox_WCT.ValueChangedFcn = createCallbackFcn(app, @CheckBox_WCTValueChanged, true);
            app.CheckBox_WCT.Value = true;
            app.CheckBox_VIDEO = uicheckbox(app.UIFigure);
            app.CheckBox_VIDEO.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VIDEOValueChanged, true);
            app.CheckBox_VIDEO.Value = true;
            app.SelectMeteorEventtoAnalyzeListBoxLabel_5 = uilabel(app.UIFigure);
            app.SelectMeteorEventtoAnalyzeListBoxLabel_5.Text = 'Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventPicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end